classdef appbase2mlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Appbase2MlappUIFigure       matlab.ui.Figure
        WelcomeLabel                matlab.ui.control.Label
        SelectedFileEditFieldLabel  matlab.ui.control.Label
        SelectedFileEditField       matlab.ui.control.EditField
        BrowseFileButton            matlab.ui.control.Button
        ConvertButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        filePath % The file to be converted
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filePath = "";
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            
            initialUserPath = strcat(getenv("USERPROFILE"),'\Documents\MATLAB');
            
            [filename, filepath, ~] = uigetfile('../*.m', 'Select M file', initialUserPath);
            app.filePath = [filepath, filename];
            
            app.SelectedFileEditField.Value = app.filePath;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            [filepath, filename] = fileparts(app.filePath);
            % The only that is missing from ".m" is "lapp"
            fileName = char(strcat(filename, '.mlapp'));
            fullPath = [filepath, '\', fileName];
            % May be there needs to be a relative path for the app to run?
            addpath(filepath);
            
            % Should be able to run in the first place
            [~, output1] = evalc(filename);
            pause(1);
            props = properties(output1);
            for c = 1:length(props)
                thisprop = props{c};
                thisprop_value = output1.(thisprop);
                if isa(thisprop_value, 'matlab.ui.Figure')
                    uiFigureOfCurrentApp = thisprop_value;
                    
                    % This code produces the same result as the code below
                    %                     status = copyfile("./app1.mlapp", fullPath);
                    %
                    %                     if status
                    %                         defaultFileR = appdesigner.internal.serialization.FileReader('./app1.mlapp');
                    %                         defaultAppdesignerData = defaultFileR.readAppDesignerData;
                    %                         defaultAppdesignerData.components.UIFigure = uiFigureOfCurrentApp;
                    %
                    %                         newFileW = appdesigner.internal.serialization.FileWriter(fullPath);
                    %                         newFileW.writeAppDesignerData(defaultAppdesignerData);
                    %                     end
                    
                    if not(isempty(uiFigureOfCurrentApp))
                        fileW = appdesigner.internal.serialization.MLAPPSerializer(fullPath,uiFigureOfCurrentApp);
                        
                        fileW.save()
                    end
                    delete(output1);
                    break;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Appbase2MlappUIFigure = uifigure('Visible', 'off');
            app.Appbase2MlappUIFigure.Name = 'Appbase 2 Mlapp';
            app.WelcomeLabel = uilabel(app.Appbase2MlappUIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.SelectedFileEditFieldLabel = uilabel(app.Appbase2MlappUIFigure);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.Appbase2MlappUIFigure, 'text');
            app.SelectedFileEditField.Editable = 'off';
            app.BrowseFileButton = uibutton(app.Appbase2MlappUIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File...';
            app.ConvertButton = uibutton(app.Appbase2MlappUIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            % Show the figure after all components are created
            app.Appbase2MlappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appbase2mlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Appbase2MlappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Appbase2MlappUIFigure)
        end
    end
end